classdef Plot_Temps_Dist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Affichage                    matlab.ui.Figure
        axe                          matlab.ui.control.UIAxes
        AfficherDropDownLabel        matlab.ui.control.Label
        AfficherDropDown             matlab.ui.control.DropDown
        NeplusafficherDropDownLabel  matlab.ui.control.Label
        NeplusafficherDropDown       matlab.ui.control.DropDown
        EnfonctionSwitchLabel        matlab.ui.control.Label
        X                            matlab.ui.control.ToggleSwitch
        DureLabel                    matlab.ui.control.Label
        TempsdedbutsLabel            matlab.ui.control.Label
        TempsderunLabel              matlab.ui.control.Label
        DistanceparcourueLabel       matlab.ui.control.Label
        maxtimevalue                 matlab.ui.control.Label
        maxtimeunit                  matlab.ui.control.Label
        maxdistancevalue             matlab.ui.control.Label
        maxdistanceunit              matlab.ui.control.Label
        axefin                       matlab.ui.control.NumericEditField
        axedebut                     matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Main app
        Xplot = 't'; % Liste des affichages en X
        Yplot = [0 0 0 0 0 0 0]; % Liste des affichages en Y
        fin %fin du plot
        debut % Temps de début du plot
    end

    methods (Access = private)    
    
        function UpdatePlot(app)
            %Cette fonction met à jour le graph selon les options choisis
            hold(app.axe,'off')
            cla(app.axe,'reset')

            m = 0;
            if (app.Yplot(1) || app.Yplot(2) || app.Yplot(3) || app.Yplot(4) || app.Yplot(5)|| app.Yplot(6)) && app.Yplot(7) %On doit créer deux échelles
                m = 1;
            end
            
            for k=1:length(app.Yplot)
                if strcmp(app.Xplot,'t')
                    if app.Yplot(k)
                        if k==1
                            plot(app.axe,app.CallingApp.Tacc,app.CallingApp.AccelX, 'DisplayName','AccelX');
                            hold(app.axe,'on')
                        elseif k==2
                            plot(app.axe,app.CallingApp.Tacc,app.CallingApp.AccelY, 'DisplayName','AccelY');
                            hold(app.axe,'on')
                        elseif k==3
                            plot(app.axe,app.CallingApp.Tacc,app.CallingApp.AccelZ, 'DisplayName','AccelZ');
                            hold(app.axe,'on')  
                        elseif k==4
                            plot(app.axe,app.CallingApp.Tacc,app.CallingApp.Yaw, 'DisplayName','Yaw');
                            hold(app.axe,'on') 
                        elseif k==5
                            plot(app.axe,app.CallingApp.Tacc,app.CallingApp.Pitch, 'DisplayName','Pitch');
                            hold(app.axe,'on')
                        elseif k==6
                            plot(app.axe,app.CallingApp.Tacc,app.CallingApp.Roll, 'DisplayName','Roll');
                            hold(app.axe,'on')
                        elseif k==7
                            if m
                                yyaxis(app.axe,'right')
                                plot(app.axe,app.CallingApp.Tgps,app.CallingApp.Speed, 'DisplayName','Speed');
                            else
                                plot(app.axe,app.CallingApp.Tgps,app.CallingApp.Speed, 'DisplayName','Speed');
                            end
                            
                        end 
                    end
                else
                    if app.Yplot(k)
                        if k==1
                            plot(app.axe,app.CallingApp.distance,app.CallingApp.AccelX_GPS, 'DisplayName','AccelX');
                            hold(app.axe,'on')
                        elseif k==2
                            plot(app.axe,app.CallingApp.distance,app.CallingApp.AccelY_GPS, 'DisplayName','AccelY');
                            hold(app.axe,'on')
                        elseif k==3
                            plot(app.axe,app.CallingApp.distance,app.CallingApp.AccelZ_GPS, 'DisplayName','AccelZ');
                            hold(app.axe,'on')
                        elseif k==4
                            plot(app.axe,app.CallingApp.distance,app.CallingApp.Yaw_GPS, 'DisplayName','Yaw');
                            hold(app.axe,'on') 
                        elseif k==5
                            plot(app.axe,app.CallingApp.distance,app.CallingApp.Pitch_GPS, 'DisplayName','Pitch');
                            hold(app.axe,'on')
                        elseif k==6
                            plot(app.axe,app.CallingApp.distance,app.CallingApp.Roll_GPS, 'DisplayName','Roll');
                            hold(app.axe,'on')
                        elseif k==7
                            if m
                                yyaxis(app.axe,'right')
                                plot(app.axe,app.CallingApp.distance,app.CallingApp.Speed, 'DisplayName','Speed');
                            else
                                plot(app.axe,app.CallingApp.distance,app.CallingApp.Speed, 'DisplayName','Speed');
                            end
                        end
                     end
                end
            end
            
            legend(app.axe,'show')
            yyaxis(app.axe,'left')
            axis(app.axe, [app.debut app.fin -inf inf])
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = Mainapp;
            app.debut = 0;
            app.fin=app.CallingApp.Time(length(app.CallingApp.Time));              
            
            app.maxtimevalue.Text = int2str(app.CallingApp.Time(end));
            app.maxdistancevalue.Text = int2str(app.CallingApp.distance(end));
        end

        % Value changed function: X
        function XValueChanged(app, event)
            value = app.X.Value;
            if value(2)=='u'
                app.debut = 0;
                app.fin = app.CallingApp.Time(end);
                app.axe.XLabel.String = 'temps (s)';
                app.DureLabel.Text = 'Durée affichée(s)';
                app.TempsdedbutsLabel.Text = 'Temps de début (s)';
                app.Xplot = 't';
            else
                app.debut = 0;
                app.fin = app.CallingApp.distance(end);
                app.axe.XLabel.String = 'Distance affichée (m)';
                app.DureLabel.Text = 'Distance (m)';
                app.TempsdedbutsLabel.Text = 'Distance de début (m)';
                app.Xplot = 'd';
            end
            UpdatePlot(app);
        end

        % Value changed function: axedebut
        function debutValueChanging(app, event)
            changingValue = event.Value;
            app.debut = changingValue;
            UpdatePlot(app);
        end

        % Value changed function: AfficherDropDown
        function AfficherDropDownValueChanged(app, event)
            name = app.AfficherDropDown.Value;
                if strcmp(name,'AccelX')
                    app.Yplot(1) = 1;
                elseif strcmp(name,'AccelY')
                    app.Yplot(2) = 1;   
                elseif strcmp(name,'AccelZ')
                    app.Yplot(3) = 1;  
                elseif strcmp(name,'Yaw')
                    app.Yplot(4) = 1;
                elseif strcmp(name,'Pitch')
                    app.Yplot(5) = 1;
                elseif strcmp(name,'Roll')
                    app.Yplot(6) = 1;
                elseif strcmp(name,'Speed')
                    app.Yplot(7) = 1;
                end
                UpdatePlot(app);
        end

        % Value changed function: NeplusafficherDropDown
        function NeplusafficherDropDownValueChanged(app, event)
            name = app.NeplusafficherDropDown.Value;
                if strcmp(name,'AccelX')
                    app.Yplot(1) = 0;
                elseif strcmp(name,'AccelY')
                    app.Yplot(2) = 0;   
                elseif strcmp(name,'AccelZ')
                    app.Yplot(3) = 0;  
                elseif strcmp(name,'Yaw')
                    app.Yplot(4) = 0;
                elseif strcmp(name,'Pitch')
                    app.Yplot(5) = 0;
                elseif strcmp(name,'Roll')
                    app.Yplot(6) = 0;
                elseif strcmp(name,'Speed')
                    app.Yplot(7) = 0;
                end
            UpdatePlot(app);
        end

        % Value changed function: axefin
        function FinValueChanging(app, event)
            changingValue = event.Value;
            app.fin = changingValue;
            UpdatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Affichage = uifigure;
            app.Affichage.Name = 'UI Figure';
            app.axe = uiaxes(app.Affichage);
            xlabel(app.axe, 'Temps (s)')
            ylabel(app.axe, 'Y')
            app.AfficherDropDownLabel = uilabel(app.Affichage);
            app.AfficherDropDownLabel.Text = 'Afficher';
            app.AfficherDropDown = uidropdown(app.Affichage);
            app.AfficherDropDown.Items = {'AccelX', 'AccelY', 'AccelZ', 'Yaw', 'Pitch', 'Roll', 'Speed'};
            app.AfficherDropDown.ValueChangedFcn = createCallbackFcn(app, @AfficherDropDownValueChanged, true);
            app.AfficherDropDown.Value = 'AccelX';
            app.NeplusafficherDropDownLabel = uilabel(app.Affichage);
            app.NeplusafficherDropDownLabel.Text = 'Ne plus afficher';
            app.NeplusafficherDropDown = uidropdown(app.Affichage);
            app.NeplusafficherDropDown.Items = {'AccelX', 'AccelY', 'AccelZ', 'Yaw', 'Pitch', 'Roll', 'Speed'};
            app.NeplusafficherDropDown.ValueChangedFcn = createCallbackFcn(app, @NeplusafficherDropDownValueChanged, true);
            app.NeplusafficherDropDown.Value = 'AccelX';
            app.EnfonctionSwitchLabel = uilabel(app.Affichage);
            app.EnfonctionSwitchLabel.Text = 'En fonction';
            app.X = uiswitch(app.Affichage, 'toggle');
            app.X.Items = {'Du temps ', 'De la distance'};
            app.X.ValueChangedFcn = createCallbackFcn(app, @XValueChanged, true);
            app.X.Value = 'Du temps ';
            app.DureLabel = uilabel(app.Affichage);
            app.DureLabel.Text = 'Temps de fin (s)';
            app.TempsdedbutsLabel = uilabel(app.Affichage);
            app.TempsdedbutsLabel.Text = 'Temps de début (s)';
            app.TempsderunLabel = uilabel(app.Affichage);
            app.TempsderunLabel.Text = 'Temps de run :';
            app.DistanceparcourueLabel = uilabel(app.Affichage);
            app.DistanceparcourueLabel.Text = 'Distance parcourue :';
            app.maxtimevalue = uilabel(app.Affichage);
            app.maxtimevalue.Text = '10';
            app.maxtimeunit = uilabel(app.Affichage);
            app.maxtimeunit.Text = 's';
            app.maxdistancevalue = uilabel(app.Affichage);
            app.maxdistancevalue.Text = '300';
            app.maxdistanceunit = uilabel(app.Affichage);
            app.maxdistanceunit.Text = 'm';
            app.axedebut = uieditfield(app.Affichage, 'numeric');
            app.axedebut.Limits = [0 Inf];
            app.axedebut.ValueChangedFcn = createCallbackFcn(app, @debutValueChanging, true);
            app.axefin = uieditfield(app.Affichage, 'numeric');
            app.axefin.Limits = [0 Inf];
            app.axefin.ValueChangedFcn = createCallbackFcn(app, @FinValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Plot_Temps_Dist(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Affichage)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Affichage)
        end
    end
end