classdef Vital < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BatteryVoltageVoltPanel  matlab.ui.container.Panel
        MinLabel                 matlab.ui.control.Label
        AverageLabel             matlab.ui.control.Label
        MaxLabel                 matlab.ui.control.Label
        Vmax                     matlab.ui.control.Label
        Vmoy                     matlab.ui.control.Label
        Vmin                     matlab.ui.control.Label
        WaterTemperaturecPanel   matlab.ui.container.Panel
        MaxLabel_2               matlab.ui.control.Label
        AverageLabel_2           matlab.ui.control.Label
        TwaterMax                matlab.ui.control.Label
        TwaterMoy                matlab.ui.control.Label
        AirinfoPressureinbartempincPanel  matlab.ui.container.Panel
        PressuremaxLabel         matlab.ui.control.Label
        AveragetemperaureLabel   matlab.ui.control.Label
        PairMax                  matlab.ui.control.Label
        TairMoy                  matlab.ui.control.Label
        OilPressurePanel         matlab.ui.container.Panel
        PressuremaxLabel_2       matlab.ui.control.Label
        PoilMax                  matlab.ui.control.Label
        Label9                   matlab.ui.control.Label
        Poilmoy                  matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        SignalsPanel             matlab.ui.container.Panel
        RPMButton                matlab.ui.control.CheckBox
        WaterTempButton          matlab.ui.control.CheckBox
        OilPressureButton        matlab.ui.control.CheckBox
        AirTempButton            matlab.ui.control.CheckBox
        AirPressureButton        matlab.ui.control.CheckBox
        BatteryVoltageButton     matlab.ui.control.CheckBox
    end


    properties (Access = private)
        Callingapp % Description
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Callingapp = Mainapp;
            
            %battery
            app.Vmax.Text = int2str(max(app.Callingapp.Vbat));
            app.Vmoy.Text = int2str(mean(app.Callingapp.Vbat,'native'));
            app.Vmin.Text = int2str(min(app.Callingapp.Vbat));
            
            %water
            app.TwaterMax.Text = int2str(max(app.Callingapp.Twater)); 
            for k=1:length(app.Callingapp.Twater)
                if app.Callingapp.RPM(k)>100
                    L(k) = app.Callingapp.Twater(k);
                end
            end
            
            app.TwaterMoy.Text = int2str(mean(L,'native'));
            
            %Air
            app.PairMax.Text = int2str(max(app.Callingapp.Pair));
            app.TairMoy.Text = int2str(mean(app.Callingapp.Tair,'native'));
            
            %oil
            L = [];
            app.PoilMax.Text = int2str(max(app.Callingapp.Poil)); 
            for k=1:length(app.Callingapp.Poil)
                if app.Callingapp.RPM(k)>100
                    L(k) = app.Callingapp.Poil(k);
                end
            end
            app.Poilmoy.Text = int2str(mean(L,'native'));
            
            %check box
            app.RPMButton.Value = 1;
            app.WaterTempButton.Value = 1;
            app.OilPressureButton.Value = 1;
            app.AirTempButton.Value = 1;
            app.AirPressureButton.Value = 1;
            app.BatteryVoltageButton.Value = 1;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            n = app.RPMButton.Value + app.WaterTempButton.Value + app.OilPressureButton.Value + app.AirTempButton.Value + app.AirPressureButton.Value + app.BatteryVoltageButton.Value;
            A=[0 0 0 0 0];
            if app.RPMButton.Value
                rpm = subplot(n,5,[ 1 2 3 4 5]);
                plot(rpm,app.Callingapp.Tacc,app.Callingapp.RPM)
                title(rpm,'RPM')
                grid(rpm,'on')
                A(1)=1;
            end
            if app.WaterTempButton.Value
                Twater = subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5]);
                plot(Twater,app.Callingapp.Tacc,app.Callingapp.Twater)
                title(Twater,'Water Temperrature')
                grid(Twater,'on')
                A(2) = 1;
            end
            if app.OilPressureButton.Value
                poil=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5]);
                plot(poil,app.Callingapp.Tacc,app.Callingapp.Poil)
                title(poil,'Oil Pressure')
                grid(poil,'on')
                A(3)=1;
            end
            if app.AirTempButton.Value
                Tair=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5]);
                plot(Tair,app.Callingapp.Tacc,app.Callingapp.Tair)
                title(Tair,'Air Temperature')
                grid(Tair,'on')
                A(4)=1;
            end
            if app.AirPressureButton.Value
                Pair=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5]);
                plot(Pair,app.Callingapp.Tacc,app.Callingapp.Pair)
                title(Pair,'Air Pressure')
                grid(Pair,'on')
                A(5)=1;
            end
            if app.BatteryVoltageButton.Value
                BV=subplot(n,5,[ sum(A)*5+1  sum(A)*5+2 sum(A)*5+3 sum(A)*5+4 sum(A)*5+5]);
                plot(BV,app.Callingapp.Tacc,app.Callingapp.Vbat)
                title(BV,'Battery Voltage')
                grid(BV,'on')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BatteryVoltageVoltPanel = uipanel(app.UIFigure);
            app.BatteryVoltageVoltPanel.Title = 'Battery Voltage (Volt)';
            app.MinLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.MinLabel.Text = 'Min :';
            app.AverageLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.AverageLabel.Text = 'Average :';
            app.MaxLabel = uilabel(app.BatteryVoltageVoltPanel);
            app.MaxLabel.Text = 'Max :';
            app.Vmax = uilabel(app.BatteryVoltageVoltPanel);
            app.Vmoy = uilabel(app.BatteryVoltageVoltPanel);
            app.Vmin = uilabel(app.BatteryVoltageVoltPanel);
            app.WaterTemperaturecPanel = uipanel(app.UIFigure);
            app.WaterTemperaturecPanel.Title = 'Water Temperature (°c)';
            app.MaxLabel_2 = uilabel(app.WaterTemperaturecPanel);
            app.MaxLabel_2.Text = 'Max :';
            app.AverageLabel_2 = uilabel(app.WaterTemperaturecPanel);
            app.AverageLabel_2.Text = 'Average :';
            app.TwaterMax = uilabel(app.WaterTemperaturecPanel);
            app.TwaterMoy = uilabel(app.WaterTemperaturecPanel);
            app.AirinfoPressureinbartempincPanel = uipanel(app.UIFigure);
            app.AirinfoPressureinbartempincPanel.Title = 'Air info (Pressure in bar, temp in °c)';
            app.PressuremaxLabel = uilabel(app.AirinfoPressureinbartempincPanel);
            app.PressuremaxLabel.Text = 'Pressure max :';
            app.AveragetemperaureLabel = uilabel(app.AirinfoPressureinbartempincPanel);
            app.AveragetemperaureLabel.Text = 'Average temperaure :';
            app.PairMax = uilabel(app.AirinfoPressureinbartempincPanel);
            app.TairMoy = uilabel(app.AirinfoPressureinbartempincPanel);
            app.OilPressurePanel = uipanel(app.UIFigure);
            app.OilPressurePanel.Title = 'Oil Pressure';
            app.PressuremaxLabel_2 = uilabel(app.OilPressurePanel);
            app.PressuremaxLabel_2.Text = 'Pressure max :';
            app.PoilMax = uilabel(app.OilPressurePanel);
            app.Label9 = uilabel(app.OilPressurePanel);
            app.Label9.Text = {'Average Pressure while'; 'motor running:'};
            app.Poilmoy = uilabel(app.OilPressurePanel);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalsPanel = uipanel(app.UIFigure);
            app.SignalsPanel.Title = 'Signals';
            app.RPMButton = uicheckbox(app.SignalsPanel);
            app.RPMButton.Text = 'RPM';
            app.WaterTempButton = uicheckbox(app.SignalsPanel);
            app.WaterTempButton.Text = 'Water Temp';
            app.OilPressureButton = uicheckbox(app.SignalsPanel);
            app.OilPressureButton.Text = 'Oil Pressure';
            app.AirTempButton = uicheckbox(app.SignalsPanel);
            app.AirTempButton.Text = 'Air Temp';
            app.AirPressureButton = uicheckbox(app.SignalsPanel);
            app.AirPressureButton.Text = 'Air Pressure';
            app.BatteryVoltageButton = uicheckbox(app.SignalsPanel);
            app.BatteryVoltageButton.Text = 'Battery voltage';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vital(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end