classdef datavisu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DataMenu              matlab.ui.container.Menu
        Menu                  matlab.ui.container.Menu
        PlotMenu              matlab.ui.container.Menu
        tempsdistanceMenu     matlab.ui.container.Menu
        FentresMenu           matlab.ui.container.Menu
        GForces               matlab.ui.container.Menu
        CorneringMenu         matlab.ui.container.Menu
        VitalFunctionsMenu    matlab.ui.container.Menu
        GPS                   matlab.ui.control.UIAxes
        NomdufichierlogLabel  matlab.ui.control.Label
        logfilename           matlab.ui.control.EditField
        ImporterButton        matlab.ui.control.Button
        Duree                 matlab.ui.control.Label
        Vmax                  matlab.ui.control.Label
        Gxmax                 matlab.ui.control.Label
        Gxmin                 matlab.ui.control.Label
        Gymax                 matlab.ui.control.Label
        Logo                  matlab.ui.control.UIAxes
        logLabel              matlab.ui.control.Label
        ExporterlesdonnesversleworkspaceButton  matlab.ui.control.Button
    end


    properties (Access = public)
        Time %en s
        Tacc %en s
        Tgps %en s
        X %en m
        Y %en m
        AccelX %en G
        AccelY %en G
        AccelZ %en G
        Yaw %en deg/s
        Pitch %en deg/s
        Roll %en deg/s
        Latitude %en °
        Longitude %en °
        Speed %en km
        distance % en m
        Altitude %en m
        AccelX_GPS %en G
        AccelY_GPS %en G
        AccelZ_GPS %en G
        Yaw_GPS %en deg/s
        Pitch_GPS %en deg/s
        Roll_GPS %en deg/s
        Steering %Angle de rotation du volant en radian
        XFL %déplacement des suspensions en mm
        XFR
        XRL
        XRR
        WFL %Vitesse de rotation des roues en rad/s
        WFR
        WRL
        WRR
        BPF %Pression dans le circuit de freinage en bar
        BPR
        RPM %vitesse de rotation du moteur en tour/min
        Gear %Vitesse en gangé (0 pour neutre)
        Pair %pression dans le plenum en bar
        Tair %Température dans le plenum en °c
        Twater %Température d'eau en °c
        Poil %Pression d'huile moteur
        Pfuel %Pression dans le circuit d'essence au niveau des injecteurs
        Lambda %Retour de la sonde lambda
        XG %déplacement de la guillotine en % (0 pour fermé et 100 pour ouvert)*
        Vbat %tension de la batterie
        
    end

    properties (Access = private)
        appCornering %application cornering
        Plot_app %application plot
        G_force %application Gforces
        appVital %applicatio vital functions
    end


    methods (Access = private)
        function [X,Y] = lat_longi2X_Y(~, lattitude, longitude)
            % lattitude et longitude sont les données brut en ° venant des logs
            lat_red = (lattitude - mean(lattitude))*pi/180;
            long_red = (longitude - mean(longitude))*pi/180;

            R = 6378137; % Rayon standard de la terre

            X = R .* tan(lat_red);
            Y = R .* tan(long_red);
        end
    
        function [] = track_plot(app, X, Y, speed)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h

            z = zeros(size(X'));
            col = speed';  % This is the color, vary with x in this case.
            surface(app.GPS, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.GPS);
            xlabel(app.GPS, 'X (m)')
            ylabel(app.GPS, 'Y (m)')
            c.Label.String = 'Vitesse Km/h';
            axis(app.GPS,[min(app.X)-10,max(app.X)+10,min(app.Y)-10,max(app.Y)+10]);
            set(app.GPS,'Visible','off')
        end
    
%        function [Ls, Ts] = supnan(app,L,T)
%             %fonction qui supprime les 100000 et qui créé une liste avec les temps
%             %associé au mesures
%             Ls=[];
%             Ts=[];
%             for i=1:length(L)
%                 if L(i)==100000
%                 else
%                     Ls = [Ls L(i)];
%                     Ts = [Ts T(i)];
%                 end
%             end
%        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            logo = imread('logo.png');
            imshow(logo,'Parent',app.Logo);
            axis(app.Logo, 'off');
        end

        % Button pushed function: ImporterButton
        function ImporterButtonPushed(app, event)


            % Import the data en remplacant les vides par des NaN
            raw  = dlmread_empty(strcat(app.logfilename.Value, '.log'),',',1,0,NaN);
            
            app.Time = (raw(:,1)-raw(1,1))/1000; % enregistrement de Time en s
            a = app.Time(end);
            % Suppression of the NaN raws 
            data = [];
            for i= 1:length(raw)
                if abs(raw(i,11)) >= 0 % On ne garde que les moments ou Les G sont pris
                    data = [data; raw(i,:)];
                end
            end
            
            % GPS data selection
            % data_gps = [time:1 lattitude:2 longitude:3 speed:4 height:5 distance:6 Ax:7 Ay:8 Az:9 Yaw:10 Pitch:11 Roll:12]
            data_gps = []; 
            indic = 1;
            for i=1:length(raw) %pour toutes les données
                if abs(raw(i,32)) > 0   %Si le GPS ne s'initialise plus
                    if raw(i,1) == data(indic,1) % si le temps d'échantillionage du GPS correspond à celui des Accels
                      A = data(indic,9:14); % Les accels
                   else
                     while raw(i,1) > data(indic,1) % && data_raw(i,1) > data(indic+1,1) %temps que le temps du gps dépasse celui des accels
                        indic = indic + 1;
                     end
                     a = (data(indic,9:14) - data(indic-1,9:14))./(data(indic,1) - data(indic-1,1));
                     b = data(indic,9:14) - a*data(indic,1);
                     A = a*raw(i,1) + b;
                    end
                    GPS = [raw(i,1) raw(i, 32:35) raw(i, 39) A];
                    data_gps = [data_gps; GPS];
                end
            end
            
            % data = [time(ms):1  Ax(g):9  Ay(g):10  Az(g):11  Yaw(°/s):12  Pitch(°/s):13  Roll(°/s):14]
            data = [data(:,1:31) data(:,40:end)];  %  Reshape of the data without the GPS columns
            
            
%             % Allocate imported array to column variable names
%             app.Time = (data(:,1)-data(1,1))/1000; %en seconde et remis à zeros
%             [app.AccelX, app.Tacc ]= supnan(app,data(:,2),app.Time); %en G
%             app.AccelY = supnan(app,data(:,3),app.Time); %en G
%             app.AccelZ= supnan(app,data(:,4),app.Time); %en G
%             app.Yaw = supnan(app,data(:,5),app.Time); %en deg/s
%             app.Pitch = supnan(app,data(:,6),app.Time); %en deg/s
%             app.Roll = supnan(app,data(:,7),app.Time); %en deg/s
%             [app.Latitude, app.Tgps] = supnan(app,data(:,8),app.Time);
%             app.Latitude = app.Latitude(80:length(app.Latitude));
%             app.Latitude = (app.Latitude-app.Latitude(1))*pi/180; %en radian
%             app.Longitude = supnan(app,data(:,9),app.Time);
%             app.Longitude = app.Longitude(80:length(app.Longitude)); 
%             app.Longitude = (app.Longitude-app.Longitude(1))*pi/180; %en radian
%             app.Speed = supnan(app,data(:,10),app.Time)*1.60934; %en km/h
%             app.Speed = app.Speed(80:length(app.Speed));
            
            % Allocate imported array to column variable names
            app.Tacc = (data(:,1)-data(1,1))/1000; %en seconde et remis à zeros
            app.AccelX = -data(:,9); %en G avec un moins car le boitier était à l'envers
            app.AccelY = data(:,10); %en G
            app.AccelZ= data(:,11); %en G
            app.Yaw = data(:,12); %en deg/s
            app.Pitch = data(:,13); %en deg/s
            app.Roll = data(:,14); %en deg/s
            app.Tgps = (data_gps(:,1)-data(1,1))/1000; %en seconde et remis au niveau des accels
            app.Latitude = data_gps(:,2);
            app.Longitude = data_gps(:,3);
            app.Speed = data_gps(:,4)*1.60934; %en km/h
            assignin('base','speed',app.Speed); %test
            app.distance = data_gps(:,6)*1609.34; %en m
            app.AccelX_GPS = -data_gps(:,7); %en G avec un moins car le boitier était à l'envers
            app.AccelY_GPS = data_gps(:,8); %en G
            app.AccelZ_GPS = data_gps(:,9); %en G
            app.Yaw_GPS = data_gps(:,10); %en deg/s
            app.Pitch_GPS = data_gps(:,11); %en deg/s
            app.Roll_GPS = data_gps(:,12); %en deg/s

            % Clear temporary variables
%             clearvars data raw R;
            clearvars data data_gps raw
            
            %file informations
            
            %Duree
            s = mod(round(max(app.Time)),60);
            m = ( round(max(app.Time))-mod(round(max(app.Time)),60))/60;
            app.Duree.Text = strcat('Durée du Run : ',' ',num2str(m),' min ',num2str(s),' s');       
            %Vmax
            app.Vmax.Text = strcat('Vitesse maximale : ',' ',num2str(round(max(app.Speed))),' km/h');
            %Gx max
            app.Gxmax.Text = strcat('Accélération longitudinale maximale :',' ',num2str(max(app.AccelX)),' g');   
            %Gx min
            app.Gxmin.Text = strcat('Accélération longitudinale minimale :',' ',num2str(min(app.AccelX)),' g');
            %Gy max
            app.Gymax.Text = strcat('Accélération latérale maximale :',' ',num2str(max(abs(app.AccelY))),' g');
            
            [app.X, app.Y] = lat_longi2X_Y(app, app.Latitude, app.Longitude);
            track_plot(app, app.X, app.Y, app.Speed)
%             %GPS 
%             R = 6378137;
% 
%             app.X = R .* app.Latitude;
%             app.Y = R .* app.Longitude;
%             plot(app.GPS,app.X,app.Y)
%             axis(app.GPS,[min(app.X)-10,max(app.X)+10,min(app.Y)-10,max(app.Y)+10]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Plot_app)
            delete(app.G_force)
            delete(app.appCornering)
            delete(app)
        end

        % Menu selected function: tempsdistanceMenu
        function tempsdistanceMenuSelected(app, event)
            app.Plot_app = Plot_Temps_Dist(app);
        end

        % Menu selected function: GForces
        function GForcesMenuSelected(app, event)
            app.G_force = Gforce(app);
        end

        % Menu selected function: CorneringMenu
        function CorneringMenuSelected(app, event)
            app.appCornering = Cornering(app);
        end

        % Button pushed function: 
        % ExporterlesdonnesversleworkspaceButton
        function ExporterlesdonnesversleworkspaceButtonPushed(app, event)
            assignin('base',"Time",app.Time);
            assignin('base',"Tacc",app.Tacc);
            assignin('base',"Tgps",app.Tgps);
            assignin('base',"X",app.X);
            assignin('base',"Y",app.Y);
            assignin('base',"AccelX",app.AccelX);
            assignin('base',"AccelY",app.AccelY);
            assignin('base',"AccelZ",app.AccelZ);
            assignin('base',"Yaw",app.Yaw);
            assignin('base',"Pitch",app.Pitch);
            assignin('base',"Roll",app.Roll);
            assignin('base',"AccelX_GPS",app.AccelX_GPS);
            assignin('base',"AccelY_GPS",app.AccelY_GPS);
            assignin('base',"AccelZ_GPS",app.AccelZ_GPS);
            assignin('base',"Yaw_GPS",app.Yaw_GPS);
            assignin('base',"Pitch_GPS",app.Pitch_GPS);
            assignin('base',"Roll_GPS",app.Roll_GPS);
            assignin('base',"Latitude",app.Latitude);
            assignin('base',"Longitude",app.Longitude);
            assignin('base',"Speed",app.Speed);
            assignin('base',"distance",app.distance);
            assignin('base',"Altitude",app.Altitude);
            assignin('base',"Steering",app.Steering);
            assignin('base',"XFL",app.XFL);
            assignin('base',"XFR",app.XFR);
            assignin('base',"XRL",app.XRL);
            assignin('base',"XRR",app.XRR);
            assignin('base',"WFL",app.WFL);
            assignin('base',"WFR",app.WFR);
            assignin('base',"WRL",app.WRL);
            assignin('base',"WRR",app.WRR);
            assignin('base',"BPF",app.BPF);
            assignin('base',"BPR",app.BPR);
            assignin('base',"RPM",app.RPM);
            assignin('base',"Gear",app.gear);
            assignin('base',"Pair",app.Pair);
            assignin('base',"Tair",app.Tair);
            assignin('base',"Twater",app.Twater);
            assignin('base',"Poil",app.Poil);
            assignin('base',"Pfuel",app.Pfuel);
            assignin('base',"Lambda",app.Lambda);
            assignin('base',"XG",app.XG);
            assignin('base',"Vbat",app.Vbat);
            plot(accelX)
        end

        % Menu selected function: VitalFunctionsMenu
        function VitalFunctionsMenuSelected(app, event)
            app.appVital = Vital(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.Menu = uimenu(app.DataMenu);
            app.Menu.Text = 'Menu';
            app.PlotMenu = uimenu(app.UIFigure);
            app.PlotMenu.Text = 'Plot';
            app.tempsdistanceMenu = uimenu(app.PlotMenu);
            app.tempsdistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @tempsdistanceMenuSelected, true);
            app.tempsdistanceMenu.Text = '/temps,distance';
            app.FentresMenu = uimenu(app.UIFigure);
            app.FentresMenu.Text = 'Fenêtres';
            app.GForces = uimenu(app.FentresMenu);
            app.GForces.MenuSelectedFcn = createCallbackFcn(app, @GForcesMenuSelected, true);
            app.GForces.Text = 'G-Forces';
            app.CorneringMenu = uimenu(app.FentresMenu);
            app.CorneringMenu.MenuSelectedFcn = createCallbackFcn(app, @CorneringMenuSelected, true);
            app.CorneringMenu.Text = 'Cornering';
            app.VitalFunctionsMenu = uimenu(app.FentresMenu);
            app.VitalFunctionsMenu.MenuSelectedFcn = createCallbackFcn(app, @VitalFunctionsMenuSelected, true);
            app.VitalFunctionsMenu.Text = 'Vital Functions';
            app.GPS = uiaxes(app.UIFigure);
            title(app.GPS, 'Coordonnées GPS')
            app.NomdufichierlogLabel = uilabel(app.UIFigure);
            app.NomdufichierlogLabel.Text = 'Nom du fichier log:';
            app.logfilename = uieditfield(app.UIFigure, 'text');
            app.ImporterButton = uibutton(app.UIFigure, 'push');
            app.ImporterButton.ButtonPushedFcn = createCallbackFcn(app, @ImporterButtonPushed, true);
            app.ImporterButton.Text = 'Importer';
            app.Duree = uilabel(app.UIFigure);
            app.Duree.Text = 'Durée du Run : ';
            app.Vmax = uilabel(app.UIFigure);
            app.Vmax.Text = 'Vitesse maximale :';
            app.Gxmax = uilabel(app.UIFigure);
            app.Gxmax.Text = 'Accélération longitudinale maximale :';
            app.Gxmin = uilabel(app.UIFigure);
            app.Gxmin.Text = 'Accélération longitudinale minimale :';
            app.Gymax = uilabel(app.UIFigure);
            app.Gymax.Text = 'Accélération latérale maximale :';
            app.Logo = uiaxes(app.UIFigure);
            app.logLabel = uilabel(app.UIFigure);
            app.logLabel.Text = '.log';
            app.ExporterlesdonnesversleworkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporterlesdonnesversleworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporterlesdonnesversleworkspaceButtonPushed, true);
            app.ExporterlesdonnesversleworkspaceButton.Text = 'Exporter les données vers le workspace';
        end
    end

    methods (Access = public)

        % Construct app
        function app = datavisu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end