classdef Cornering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cornering_tendency          matlab.ui.Figure
        plot                        matlab.ui.control.UIAxes
        SteeringCheck               matlab.ui.control.CheckBox
        Yawcheck                    matlab.ui.control.CheckBox
        AverageundersteerLabel      matlab.ui.control.Label
        Average                     matlab.ui.control.Label
        OversteeringtendencyLabel   matlab.ui.control.Label
        UndersteeringtendencyLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Datavisu
        Balance_Yaw % balance du véhicule calculer à partir du Yaw
        M_Yaw % Liste des balance à prendre dans la moyenne
        Balance_Steer % balance du véhicule calculer à partir du volant
        M_Steer % liste de sbalance à prendre en compte dans la moyenne
        Steering10 %Steering échantillonné en 10Hz
        WheelBase = 1.575; %Wheel base en m
        WheelAngle %angle des roues en 10Hz aussi 
        majcolorbar %
        T50;
        T10;
    end

    methods (Access = private)
    
        function majPlot(app,col)
            cla(app.plot,'reset')
            z = zeros(size(app.CallingApp.X'));
            col = col';  % This is the color, vary with x in this case.
            surface(app.plot, [app.CallingApp.X';app.CallingApp.X'],[app.CallingApp.Y';app.CallingApp.Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            if app.majcolorbar 
                colorbar(app.plot,'off');
            end
            c = colorbar(app.plot);
            xlabel(app.plot, 'X (m)')
            ylabel(app.plot, 'Y (m)')
            axis(app.plot,[min(app.CallingApp.X)-10,max(app.CallingApp.X)+10,min(app.CallingApp.Y)-10,max(app.CallingApp.Y)+10]);
            set(app.plot,'Visible','off')
        end
        
        function [alpha_int, alpha_ext] = lien_angle_rack(~,angle_volant)

    % Angle_volant est en degré, définie aritrairement positif si il est tourné
    % dans le sens trigonométrique
    % alpha_int : en deg, angle de la roue intérieure et positif pour un angle
    % de volant positif
    % alpha_ext : en deg, angle de la roue exterieure et positif pour un angle
    % de volant positif
    
    spr = 80; % mm/rev speed ratio, donnée par formula seven
    Tr = 564.45*2; %mm, Voie de la voiture
    Lc = 450; %mm, Longueur de la crémaillère
    b = 55; % mm, longueur du bras de levier dans le PM
    Lb = 353.7; %mm, Longueur de la biellette de direction
    h = 80; %mm, Distance entre l'axe des roues avant et la crémaillère
    
    angle_volant = angle_volant / 360; % Pour avoir l'angle du volant en tour
    lambda = angle_volant*spr; % En mm, déplacement de la crémaillère 
    
    f = @(lambda) lambda - Tr/2 + Lc/2;
    g = @(lambda) (f(lambda).^2 + b^2 - Lb^2 + h^2) / (2*b);
    
    alpha_0 = asin(g(0) ./ sqrt(f(0).^2 + h^2)) + acos(h ./ sqrt(f(0).^2 + h^2));
    
    alpha_int_ini = asin(g(lambda) ./ sqrt(f(lambda).^2 + h^2)) + acos(h ./ sqrt(f(lambda).^2 + h^2)) - alpha_0;
    alpha_ext_ini = asin(g(-lambda) ./ sqrt(f(-lambda).^2 + h^2)) + acos(h ./ sqrt(f(-lambda).^2 + h^2)) - alpha_0;
    
    alpha_int_ini = alpha_int_ini*180/pi;
    alpha_ext_ini = alpha_ext_ini*180/pi;
    
    if angle_volant >= 0
        alpha_int = abs(alpha_int_ini);
        alpha_ext = abs(alpha_ext_ini);
    else
        alpha_int = - abs(alpha_ext_ini);
        alpha_ext = - abs(alpha_int_ini);
        
    end
    
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.Cornering_tendency.Name = 'Cornering';
            app.CallingApp = Mainapp;
            app.Yawcheck.Value = 0;
            app.SteeringCheck.Value = 1;
            app.majcolorbar = 0;
            
            app.T50=freq2time(app.CallingApp.data_struct,50);
            app.T10=freq2time(app.CallingApp.data_struct,10);
            
            %Passage de l'angle au volant en 10hz
            app.Steering10 = interp1(app.T50,get_data(app.CallingApp.data_struct,"Volant"),app.T10);
            
            %Angle des roues
            for i=1:length(app.Steering10)
                [int, ext] = lien_angle_rack(app,-app.Steering10(i));
                app.WheelAngle(i) = 1/2*ext+1/2*int;
            end
            Speed =get_data(app.CallingApp.data_struct,"Speed");
            Yaw_GPS =get_data(app.CallingApp.data_struct,"Yaw_GPS");
            AccelX_GPS =get_data(app.CallingApp.data_struct,"AccelX_GPS");
            AccelY_GPS =get_data(app.CallingApp.data_struct,"AccelY_GPS");
                    
            %Calcul des moyennes
            for k=1:length(app.Speed)
                if app.CallingApp.Speed(k)>5 %si la vitesse dépasse 5 km/h (pas d'intérêt sinon)

                    app.Balance_Yaw(k) = abs(Yaw_GPS(k))-abs(9.81*AccelX_GPS(k)/(0.277*Speed(k)))*57.3;
                    app.M_Yaw = [app.M_Yaw app.Balance_Yaw(k)];
                    app.Balance_Steer(k) = app.WheelAngle(k) - abs(57.3*app.WheelBase*9.81*AccelY_GPS(k)/(Speed(k)^2)*3.6^2);
                    app.M_Steer = [app.M_Steer app.Balance_Steer(k)];
                else
                    app.Balance_Yaw(k) = 0;
                    app.Balance_Steer(k) = 0;
                end
            end
            
            app.Balance_Yaw = app.Balance_Yaw';
            app.Balance_Steer = app.Balance_Steer';
            majPlot(app,app.Balance_Steer)
            app.majcolorbar = 1;
            app.Average.Text = num2str(mean(app.M_Steer,'native'),2);
        end

        % Value changed function: Yawcheck
        function YawcheckValueChanged(app, event)
            value = app.Yawcheck.Value;
            if value == 1
                app.SteeringCheck.Value = 0;
                majPlot(app,app.Balance_Yaw)
                app.Average.Text = num2str(mean(app.M_Yaw,'native'),2);
                app.OversteeringtendencyLabel.Text = ['Going more', newline, 'oversteering'];
                app.UndersteeringtendencyLabel.Text = ['Going less', newline, 'oversteering'];
            end
        end

        % Value changed function: SteeringCheck
        function SteeringCheckValueChanged(app, event)
            value = app.SteeringCheck.Value;
            if value == 1
                app.Yawcheck.Value = 0;
                majPlot(app,app.Balance_Steer)
                app.Average.Text = num2str(mean(app.M_Steer,'native'),2);
                app.OversteeringtendencyLabel.Text = ['Oversteering', newline, 'tendency'];
                app.UndersteeringtendencyLabel.Text = ['Understeering',newline, 'tendency'];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cornering_tendency = uifigure('Visible', 'off');
            app.Cornering_tendency.Name = 'UI Figure';
            app.plot = uiaxes(app.Cornering_tendency);
            title(app.plot, 'Understeer angle (°) projected on the track')
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.SteeringCheck = uicheckbox(app.Cornering_tendency);
            app.SteeringCheck.ValueChangedFcn = createCallbackFcn(app, @SteeringCheckValueChanged, true);
            app.SteeringCheck.Text = 'Understeer tendency';
            app.Yawcheck = uicheckbox(app.Cornering_tendency);
            app.Yawcheck.ValueChangedFcn = createCallbackFcn(app, @YawcheckValueChanged, true);
            app.Yawcheck.Text = 'Attitude velocity';
            app.Yawcheck.Value = true;
            app.AverageundersteerLabel = uilabel(app.Cornering_tendency);
            app.AverageundersteerLabel.Text = 'Average understeer (°) =  ';
            app.Average = uilabel(app.Cornering_tendency);
            app.OversteeringtendencyLabel = uilabel(app.Cornering_tendency);
            app.OversteeringtendencyLabel.Text = {'Oversteering'; 'tendency'};
            app.UndersteeringtendencyLabel = uilabel(app.Cornering_tendency);
            app.UndersteeringtendencyLabel.Text = {'Understeering'; 'tendency'};
            % Show the figure after all components are created
            app.Cornering_tendency.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cornering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cornering_tendency)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cornering_tendency)
        end
    end
end