classdef Dampers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        GraphTab                matlab.ui.container.Tab
        AxesFL                  matlab.ui.control.UIAxes
        AxesRL                  matlab.ui.control.UIAxes
        AxesFR                  matlab.ui.control.UIAxes
        AxesRR                  matlab.ui.control.UIAxes
        SuspensionSensorsLabel  matlab.ui.control.Label
        TravelCheckBox          matlab.ui.control.CheckBox
        SpeedCheckBox           matlab.ui.control.CheckBox
        SpeedHistogramCheckBox  matlab.ui.control.CheckBox
        Table                   matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp %Datavisu
        Time
        XFL % filtered data
        XFR
        XRL
        XRR
        Filter_order
        VFL
        VFR
        VRL
        VRR
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.UIFigure.Name = 'Dampers';
            app.CallingApp = Mainapp;
            app.TravelCheckBox.Value = 1;
            app.SpeedCheckBox.Value = 0;
            app.SpeedHistogramCheckBox.Value = 1;
            % Récupération des données
            app.XFR = get_data(app.CallingApp.data_struct,'xFR'); 
            app.XFL = get_data(app.CallingApp.data_struct,'xFL'); 
            app.XRR = get_data(app.CallingApp.data_struct,'xRR'); 
            id_XRL = get_data_index(app.CallingApp.data_struct,'xRL'); 
            app.XRL = app.CallingApp.data_struct(id_XRL).Data;
        
            app.Time =  freq2time(app.CallingApp.data_struct,app.CallingApp.data_struct(id_XRL).Frequency);
            periode = 1/app.CallingApp.data_struct(id_XRL).Frequency;
            %Calcul de la dérivée
            app.VFR = diff(app.XFR)/periode;
            app.VFL = diff(app.XFL)/periode;
            app.VRR = diff(app.XRR)/periode;
            app.VRL = diff(app.XRL)/periode;
            %Affichage des histogrammes
            hFL = histogram(app.AxesFL,app.VFL,20);
            hFR = histogram(app.AxesFR,app.VFR,20);
            hRL = histogram(app.AxesRL,app.VRL,20);
            hRR = histogram(app.AxesRR,app.VRR,20);
            Ymax = max(hFL.Values,hFR.Values,hRL.Values,hRR.Values);
            Xmin = -50; %Peut être à changer
            Ymax = 50;
            axis(app.AxesFL,[Xmin,Xmax,0,Ymax])
            axis(app.AxesFR,[Xmin,Xmax,0,Ymax])
            axis(app.AxesRL,[Xmin,Xmax,0,Ymax])
            axis(app.AxesRR,[Xmin,Xmax,0,Ymax])
            
            % Calcul des données de la table
            %FL
            sizetot = length(app.VFL);
            app.UITable.Data(2,1) = 'Front Left Tire';
            app.UITable.Data(2,2) = 'Low speed';
            app.UITable.Data(3,2) = 'High speed';
            app.UITable.Data(2,3) = round(sum(app.VFL<0 & app.VFL>-25)/sizetot*100,1);
            app.UITable.Data(2,4) = round(sum(app.VFL>0 & app.VFL<25)/sizetot*100,1);
            app.UITable.Data(3,3) = round(sum(app.VFL<-25)/sizetot*100,1);
            app.UITable.Data(3,4) = round(sum(app.VFL>25)/sizetot*100,1);
            %FR
            app.UITable.Data(4,1) = 'Front Right Tire';
            app.UITable.Data(4,2) = 'Low speed';
            app.UITable.Data(5,2) = 'High speed';
            app.UITable.Data(4,3) = round(sum(app.VFR<0 & app.VFR>-25)/sizetot*100,1);
            app.UITable.Data(4,4) = round(sum(app.VFR>0 & app.VFR<25)/sizetot*100,1);
            app.UITable.Data(5,3) = round(sum(app.VFR<-25)/sizetot*100,1);
            app.UITable.Data(5,4) = round(sum(app.VFR>25)/sizetot*100,1);
            %RL
            sizetot = length(app.VFL);
            app.UITable.Data(6,1) = 'Rear Left Tire';
            app.UITable.Data(6,2) = 'Low speed';
            app.UITable.Data(7,2) = 'High speed';
            app.UITable.Data(6,3) = round(sum(app.VRL<0 & app.VRL>-25)/sizetot*100,1);
            app.UITable.Data(6,4) = round(sum(app.VRL>0 & app.VRL<25)/sizetot*100,1);
            app.UITable.Data(7,3) = round(sum(app.VRL<-25)/sizetot*100,1);
            app.UITable.Data(7,4) = round(sum(app.VRL>25)/sizetot*100,1);
            %FR
            app.UITable.Data(8,1) = 'Rear Right Tire';
            app.UITable.Data(8,2) = 'Low speed';
            app.UITable.Data(9,2) = 'High speed';
            app.UITable.Data(8,3) = round(sum(app.VRR<0 & app.VRR>-25)/sizetot*100,1);
            app.UITable.Data(8,4) = round(sum(app.VRR>0 & app.VRR<25)/sizetot*100,1);
            app.UITable.Data(9,3) = round(sum(app.VRR<-25)/sizetot*100,1);
            app.UITable.Data(9,4) = round(sum(app.VRR>25)/sizetot*100,1);
        end

        % Value changed function: SpeedCheckBox
        function SpeedCheckBoxValueChanged(app, event)
            app.TravelCheckBox.Value = 0 ;
            app.SpeedHistogramCheckBox.Value = 0 ;
            plot(app.AxesFL,app.Time(1:end-1),app.VFL)
            plot(app.AxesFR,app.Time(1:end-1),app.VFR)
            plot(app.AxesRL,app.Time(1:end-1),app.VRL)
            plot(app.AxesRR,app.Time(1:end-1),app.VRR)
        end

        % Value changed function: SpeedHistogramCheckBox
        function SpeedHistogramCheckBoxValueChanged(app, event)
            app.TravelCheckBox.Value = 0 ;
            app.SpeedCheckBox.Value = 0 ;
            hFL = histogram(app.AxesFL,app.VFL,20);
            hFR = histogram(app.AxesFR,app.VFR,20);
            hRL = histogram(app.AxesRL,app.VRL,20);
            hRR = histogram(app.AxesRR,app.VRR,20);
            Ymax = max(hFL.Values,hFR.Values,hRL.Values,hRR.Values);
            Xmin = -50; %Peut être à changer
            Ymax = 50;
            axis(app.AxesFL,[Xmin,Xmax,0,Ymax])
            axis(app.AxesFR,[Xmin,Xmax,0,Ymax])
            axis(app.AxesRL,[Xmin,Xmax,0,Ymax])
            axis(app.AxesRR,[Xmin,Xmax,0,Ymax])
            
        end

        % Value changed function: TravelCheckBox
        function TravelCheckBoxValueChanged(app, event)
            app.SpeedCheckBox.Value = 0 ;
            app.SpeedHistogramCheckBox.Value = 0 ;
            plot(app.AxesFL,app.Time,app.XFLf)
            plot(app.AxesFR,app.Time,app.XFRf)
            plot(app.AxesRL,app.Time,app.XRLf)
            plot(app.AxesRR,app.Time,app.XRRf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.AxesFL = uiaxes(app.GraphTab);
            title(app.AxesFL, 'FL')
            xlabel(app.AxesFL, 'X')
            ylabel(app.AxesFL, 'Y')
            app.AxesRL = uiaxes(app.GraphTab);
            title(app.AxesRL, 'RL')
            xlabel(app.AxesRL, 'X')
            ylabel(app.AxesRL, 'Y')
            app.AxesFR = uiaxes(app.GraphTab);
            title(app.AxesFR, 'FR')
            xlabel(app.AxesFR, 'X')
            ylabel(app.AxesFR, 'Y')
            app.AxesRR = uiaxes(app.GraphTab);
            title(app.AxesRR, 'RR')
            xlabel(app.AxesRR, 'X')
            ylabel(app.AxesRR, 'Y')
            app.SuspensionSensorsLabel = uilabel(app.GraphTab);
            app.SuspensionSensorsLabel.Text = 'Suspension Sensors';
            app.TravelCheckBox = uicheckbox(app.GraphTab);
            app.TravelCheckBox.ValueChangedFcn = createCallbackFcn(app, @TravelCheckBoxValueChanged, true);
            app.TravelCheckBox.Text = 'Travel';
            app.SpeedCheckBox = uicheckbox(app.GraphTab);
            app.SpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeedCheckBoxValueChanged, true);
            app.SpeedCheckBox.Text = 'Speed';
            app.SpeedHistogramCheckBox = uicheckbox(app.GraphTab);
            app.SpeedHistogramCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpeedHistogramCheckBoxValueChanged, true);
            app.SpeedHistogramCheckBox.Text = 'Speed Histogram';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {''; ''; 'Rebound'; 'Bump'; 'Difference'; 'Average'};
            app.UITable.RowName = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dampers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end