classdef GPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        GPSaxe    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Mainapp % Description
    end

    methods (Access = public)
       function i = findi(~, L, val)
            %Cette fonction renvoie le premier indice auquel se trouve une valeur de la liste L supérieur à val
            i=0;
            k=1;
            while (i==0) && (k<=length(L))
                if L(k)>=val
                    i=k;
                end
                k=k+1;
            end
            if i==0
                i=length(L);
            end
       end
   
        function UpdateGPS(app,t,val)
            hold(app.GPSaxe,'off')
            plot(app.GPSaxe, app.Mainapp.X,app.Mainapp.Y)
            axis(app.GPSaxe,[min(app.Mainapp.X)-10,max(app.Mainapp.X)+10,min(app.Mainapp.Y)-10,max(app.Mainapp.Y)+10]);
            set(app.GPSaxe,'Visible','off');
            hold(app.GPSaxe,'on')
            if t
                i = findi(app,app.Mainapp.Tgps,val);
                plot(app.GPSaxe,app.Mainapp.X(i),app.Mainapp.Y(i),'o','MarkerFaceColor','r');
            else
                i = findi(app,app.Mainapp.distance,val);
                plot(app.GPSaxe,app.Mainapp.X(i),app.Mainapp.Y(i),'o','MarkerFaceColor','r');
            end 
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Mainapp = mainapp;
            plot(app.GPSaxe, app.Mainapp.X,app.Mainapp.Y)
            axis(app.GPSaxe,[min(app.Mainapp.X)-10,max(app.Mainapp.X)+10,min(app.Mainapp.Y)-10,max(app.Mainapp.Y)+10]);
            set(app.GPSaxe,'Visible','off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GPSaxe = uiaxes(app.UIFigure);
            title(app.GPSaxe, 'Title')
            xlabel(app.GPSaxe, 'X')
            ylabel(app.GPSaxe, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GPS(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end