classdef Plot_Temps_Dist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Affichage                  matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ParametersTab              matlab.ui.container.Tab
        WichlapPanel               matlab.ui.container.Panel
        Lap10                      matlab.ui.control.CheckBox
        Lap9                       matlab.ui.control.CheckBox
        Lap8                       matlab.ui.control.CheckBox
        Lap7                       matlab.ui.control.CheckBox
        Lap6                       matlab.ui.control.CheckBox
        Lap5                       matlab.ui.control.CheckBox
        Lap4                       matlab.ui.control.CheckBox
        Lap3                       matlab.ui.control.CheckBox
        Lap2                       matlab.ui.control.CheckBox
        Lap1                       matlab.ui.control.CheckBox
        Alltherun                  matlab.ui.control.CheckBox
        DisplayaccordingtoLabel    matlab.ui.control.Label
        TD                         matlab.ui.control.ToggleSwitch
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        DisplayDataList            matlab.ui.control.ListBox
        DisplayDataListBoxLabel    matlab.ui.control.Label
        AvailableDataList          matlab.ui.control.ListBox
        AvailableDataListBoxLabel  matlab.ui.control.Label
        DisplayTab                 matlab.ui.container.Tab
        Axes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Main app

    end

    methods (Access = private)    
    
        function UpdatePlot(app)
            hold(app.Axes,'on')
            if strcmp('Time',app.TD.Value)
                if app.Alltherun.Value == 1
                    for k=1:length(app.DisplayDataList.Items)
                        L = eval(char(strcat('app.CallingApp.',app.DisplayDataList.Items(k))));
                        if length(L) == length(app.CallingApp.Time)
                            plot(app.Axes, app.CallingApp.Time, L, 'DisplayName',string(app.DisplayDataList.Items(k)));
                        elseif length(L) == length(app.CallingApp.Tgps)
                            plot(app.Axes, app.CallingApp.Tgps, L, 'DisplayName',string(app.DisplayDataList.Items(k)));
                        elseif length(L) == length(app.CallingApp.Tacc)
                            plot(app.Axes, app.CallingApp.Tacc, L, 'DisplayName',string(app.DisplayDataList.Items(k)));
                        end
                    end
                else
                end
                hold(app.Axes,'off')
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = Mainapp;
            
            % choix des données
            app.DisplayDataList.Items = {};
            app.AvailableDataList.Items = {
                'AccelX' %en G
                'AccelY' %en G
                'AccelZ' %en G
                'Yaw' %en deg/s
                'Pitch' %en deg/s
                'Roll' %en deg/s
                'Speed' %en km
                'Steering' %Angle de rotation du volant en radian
                'XFL' %déplacement des suspensions en mm
                'XFR'
                'XRL'
                'XRR'
                'WFL' %Vitesse de rotation des roues en rad/s
                'WFR'
                'WRL'
                'WRR'
                'BPF' %Pression dans le circuit de freinage en bar
                'BPR'
                'RPM' %vitesse de rotation du moteur en tour/min
                'Gear' %Vitesse engangée (0 pour neutre)
                'Pair' %pression dans le plenum en bar
                'Tair' %Température dans le plenum en °c
                'Twater' %Température d'eau en °c
                'Poil' %Pression d'huile moteur
                'Pfuel' %Pression dans le circuit d'essence au niveau des injecteurs
                'Lambda' %Retour de la sonde lambda
                'TPS' %déplacement de la guillotine en % (0 pour fermé et 100 pour ouvert)*
                'Vbat' %tension de la batterie
                };
            app.AvailableDataList.Value = {};
            app.DisplayDataList.Value = {};
            %On supprime les données vide
            k = 1;
            for i=1:length(app.AvailableDataList.Items)
                if length(eval(char(strcat('app.CallingApp.',app.AvailableDataList.Items(k))))) < 1
                    app.AvailableDataList.Items(k) = [];
                else
                    k = k+1;
                end
            end
            
            % Choix des tours (pas très élégants mais bon..):
            if length(app.CallingApp.TLap)<2
                app.Lap1.Visible ='off';
            end
            if length(app.CallingApp.TLap)<3
                app.Lap2.Visible ='off';
            end
            if length(app.CallingApp.TLap)<4
                app.Lap3.Visible ='off';
            end
            if length(app.CallingApp.TLap)<5
                app.Lap4.Visible ='off';
            end
            if length(app.CallingApp.TLap)<6
                app.Lap5.Visible ='off';
            end
            if length(app.CallingApp.TLap)<7
                app.Lap6.Visible ='off';
            end
            if length(app.CallingApp.TLap)<8
                app.Lap7.Visible ='off';
            end
            if length(app.CallingApp.TLap)<9
                app.Lap8.Visible ='off';
            end
            if length(app.CallingApp.TLap)<10
                app.Lap9.Visible ='off';
            end
            if length(app.CallingApp.TLap)<11
                app.Lap10.Visible ='off';
            end
            
 
            UpdatePlot(app);
        end

        % Value changed function: AvailableDataList
        function AvailableDataListValueChanged(app, event)
            value = app.AvailableDataList.Value;
            app.DisplayDataList.Items{end + 1} = value;
            app.AvailableDataList.Items(find(strcmp(app.AvailableDataList.Items, char(value))))= [];
            app.AvailableDataList.Value = {};
            app.DisplayDataList.Value = {};
            UpdatePlot(app);
        end

        % Value changed function: DisplayDataList
        function DisplayDataListValueChanged(app, event)
            value = app.DisplayDataList.Value;
            app.AvailableDataList.Items{end + 1} = value;
            app.DisplayDataList.Items(find(strcmp(app.DisplayDataList.Items, char(value))))= [];
            app.AvailableDataList.Value = {};
            app.DisplayDataList.Value = {};
            UpdatePlot(app);
        end

        % Value changed function: Alltherun
        function AlltherunValueChanged(app, event)
            value = app.Alltherun.Value;
            if value ==1
                app.Lap1.Value = 0;
                app.Lap2.Value = 0;
                app.Lap3.Value = 0;
                app.Lap4.Value = 0;
                app.Lap5.Value = 0;
                app.Lap6.Value = 0;
                app.Lap7.Value = 0;
                app.Lap8.Value = 0;
                app.Lap9.Value = 0;
                app.Lap10.Value = 0;
            end
            UpdatePlot(app)
        end

        % Value changed function: Lap1, Lap10, Lap2, Lap3, Lap4, 
        % Lap5, Lap6, Lap7, Lap8, Lap9
        function Lap1ValueChanged(app, event)
            value = app.Lap1.Value;
            if value ==1
                app.Alltherun.Value = 0;
            end
            UpdatePlot(app)
        end

        % Value changed function: TD
        function TDValueChanged(app, event)
            value = app.TD.Value;
            if strcmp('Time',value)
                app.Alltherun.Visible = 'on';
            else
                app.Alltherun.Visible = 'off';
            end
            UpdatePlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Affichage = uifigure('Visible', 'off');
            app.Affichage.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.Affichage);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.AvailableDataListBoxLabel = uilabel(app.ParametersTab);
            app.AvailableDataListBoxLabel.Text = 'Available Data';
            app.AvailableDataList = uilistbox(app.ParametersTab);
            app.AvailableDataList.ValueChangedFcn = createCallbackFcn(app, @AvailableDataListValueChanged, true);
            app.DisplayDataListBoxLabel = uilabel(app.ParametersTab);
            app.DisplayDataListBoxLabel.Text = 'Display Data';
            app.DisplayDataList = uilistbox(app.ParametersTab);
            app.DisplayDataList.ValueChangedFcn = createCallbackFcn(app, @DisplayDataListValueChanged, true);
            app.Label = uilabel(app.ParametersTab);
            app.Label.Text = '-->';
            app.Label_2 = uilabel(app.ParametersTab);
            app.Label_2.Text = '<--';
            app.Label_3 = uilabel(app.ParametersTab);
            app.Label_3.Text = '';
            app.TD = uiswitch(app.ParametersTab, 'toggle');
            app.TD.Items = {'Distance', 'Time'};
            app.TD.ValueChangedFcn = createCallbackFcn(app, @TDValueChanged, true);
            app.TD.Value = 'Time';
            app.DisplayaccordingtoLabel = uilabel(app.ParametersTab);
            app.DisplayaccordingtoLabel.Text = 'Display according to :';
            app.WichlapPanel = uipanel(app.ParametersTab);
            app.WichlapPanel.Title = 'Wich lap ?';
            app.Alltherun = uicheckbox(app.WichlapPanel);
            app.Alltherun.ValueChangedFcn = createCallbackFcn(app, @AlltherunValueChanged, true);
            app.Alltherun.Text = 'All the run';
            app.Alltherun.Value = true;
            app.Lap1 = uicheckbox(app.WichlapPanel);
            app.Lap1.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap1.Text = 'Lap 1';
            app.Lap2 = uicheckbox(app.WichlapPanel);
            app.Lap2.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap2.Text = 'Lap 2';
            app.Lap3 = uicheckbox(app.WichlapPanel);
            app.Lap3.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap3.Text = 'Lap 3';
            app.Lap4 = uicheckbox(app.WichlapPanel);
            app.Lap4.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap4.Text = 'Lap 4';
            app.Lap5 = uicheckbox(app.WichlapPanel);
            app.Lap5.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap5.Text = 'Lap 5';
            app.Lap6 = uicheckbox(app.WichlapPanel);
            app.Lap6.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap6.Text = 'Lap 6';
            app.Lap7 = uicheckbox(app.WichlapPanel);
            app.Lap7.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap7.Text = 'Lap 7';
            app.Lap8 = uicheckbox(app.WichlapPanel);
            app.Lap8.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap8.Text = 'Lap 8';
            app.Lap9 = uicheckbox(app.WichlapPanel);
            app.Lap9.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap9.Text = 'Lap 9';
            app.Lap10 = uicheckbox(app.WichlapPanel);
            app.Lap10.ValueChangedFcn = createCallbackFcn(app, @Lap1ValueChanged, true);
            app.Lap10.Text = 'Lap 10';
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.Axes = uiaxes(app.DisplayTab);
            % Show the figure after all components are created
            app.Affichage.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_Temps_Dist(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Affichage)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Affichage)
        end
    end
end