classdef Roll_Gradient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        RollGradientTab     matlab.ui.container.Tab
        RollDropDownLabel   matlab.ui.control.Label
        RollDropDown        matlab.ui.control.DropDown
        RollGradientGLabel  matlab.ui.control.Label
        RGradient           matlab.ui.control.Label
        Offset              matlab.ui.control.Label
        RG_3                matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
        FrontrearRatioTab   matlab.ui.container.Tab
        AverageratioLabel   matlab.ui.control.Label
        meanratio           matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % datavisu app
        Roll % Roll of the car
        RG % Roll gradient
        FRoll % Front roll of the car
        FRG % front toll gradient
        RRoll % Rear roll of the car
        RRG % rear roll ratio
        ratio % distribution de raideur avant arrière
        Mratio % moyenne du ratio
        AccelY %Accélération latéral
        Time %Liste des temps 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
        app.UIFigure.Name = 'Roll gradient';
        app.CallingApp = Mainapp;   
        
        %% paramétrage Optimus
        MRf = 1.15; % front motion ratio, dampers to wheels
        MRr = 1.07; % rear motion ratio
        Tf = 1254; % front track drive en mm
        Tr = 1180; % rear track drive en mm
        % Récupération des données
        XFR = get_data(app.CallingApp.data_struct,'xFR'); 
        XFL = get_data(app.CallingApp.data_struct,'xFL'); 
        XRR = get_data(app.CallingApp.data_struct,'xRR'); 
        id_XRL = get_data_index(app.CallingApp.data_struct,'xRL'); 
        XRL = app.CallingApp.data_struct(id_XRL).Data;
        
        app.Time =  freq2time(app.CallingApp.data_struct,app.CallingApp.data_struct(id_XRL).Frequency);
        app.AccelY = get_data(app.CallingApp.data_struct,'AccelY'); 
        
        %% Calcul des roll et du ratio 
        app.Roll = rad2deg(atan(((XFR-XFL)*MRf+(XRL-XRR)*MRr)/(Tr+Tf)));
        app.FRoll = rad2deg(atan((XFR-XFL)*MRf/Tf));
        app.RRoll = rad2deg(atan((XRL-XRR)*MRr/Tr));
        for i=1:length(app.RRoll)
            if app.RRoll(i)==0
                app.ratio(i) = 1;
            else
                app.ratio(i) = abs(app.FRoll(i)/app.RRoll(i));
            end
        end
        
        %Tracé des courbes
        %roll gradient
        plot(app.UIAxes,app.AccelY,app.Roll,'*')
        xlabel(app.UIAxes,'Lateral acceleration (G)')
        ylabel(app.UIAxes, 'Roll (°)')
        hold(app.UIAxes,'on')
        p=polyfit(app.AccelY,app.Roll,1);
        app.RG=p(1);
        app.RGradient.Text = num2str(round(app.RG,4),2);
        app.Offset.Text = num2str(p(2),2);
        plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
        hold(app.UIAxes,'off')
        
        %ratio
        plot(app.UIAxes2,app.Time,app.ratio)
        xlabel(app.UIAxes2,'Time (s)')
        ylabel(app.UIAxes2,'Roll ratio')
        app.meanratio.Text = num2str(round(mean(app.ratio,'native'),2));
        assignin('base','ratio',app.ratio)
        end

        % Value changed function: RollDropDown
        function RollDropDownValueChanged(app, event)
            value = app.RollDropDown.Value;
            if strcmp(value,'All')
                plot(app.UIAxes,app.AccelY,app.Roll,'*')
                xlabel(app.UIAxes,'Lateral acceleration (G)')
                ylabel(app.UIAxes, 'Roll (°)')
                hold(app.UIAxes,'on')
                p=polyfit(app.AccelY,app.Roll,1);
                app.RG=p(1);
                app.RGradient.Text = num2str(round(app.RG,4),2);
                app.Offset.Text = num2str(p(2),2);
                plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
                hold(app.UIAxes,'off')  
            elseif strcmp(value,'Front')
                plot(app.UIAxes,app.AccelY,app.FRoll,'*')
                xlabel(app.UIAxes,'Lateral acceleration (G)')
                ylabel(app.UIAxes, 'Front Roll (°)')
                hold(app.UIAxes,'on')
                p=polyfit(app.AccelY,app.FRoll,1);
                app.FRG=p(1);
                app.RGradient.Text = num2str(round(app.FRG,4),2);
                app.Offset.Text = num2str(p(2),2);
                plot(app.UIAxes,app.CallingApp.AccelY,polyval(p,app.AccelY))
                hold(app.UIAxes,'off')
            else
                plot(app.UIAxes,app.AccelY,app.RRoll,'*')
                xlabel(app.UIAxes,'Lateral acceleration (G)')
                ylabel(app.UIAxes, 'Rear Roll (°)')
                hold(app.UIAxes,'on')
                p=polyfit(app.AccelY,app.RRoll,1);
                app.RRG=p(1);
                app.RGradient.Text = num2str(round(app.RRG,4),2);
                app.Offset.Text = num2str(p(2),2);
                plot(app.UIAxes,app.AccelY,polyval(p,app.AccelY))
                hold(app.UIAxes,'off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RollGradientTab = uitab(app.TabGroup);
            app.RollGradientTab.Title = 'Roll Gradient';
            app.RollDropDownLabel = uilabel(app.RollGradientTab);
            app.RollDropDownLabel.Text = 'Roll';
            app.RollDropDown = uidropdown(app.RollGradientTab);
            app.RollDropDown.Items = {'All', 'Front', 'Rear', ''};
            app.RollDropDown.ValueChangedFcn = createCallbackFcn(app, @RollDropDownValueChanged, true);
            app.RollDropDown.Value = 'All';
            app.RollGradientGLabel = uilabel(app.RollGradientTab);
            app.RollGradientGLabel.Text = 'Roll Gradient (°/G) :';
            app.RGradient = uilabel(app.RollGradientTab);
            app.Offset = uilabel(app.RollGradientTab);
            app.RG_3 = uilabel(app.RollGradientTab);
            app.RG_3.Text = 'Offset :';
            app.UIAxes = uiaxes(app.RollGradientTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FrontrearRatioTab = uitab(app.TabGroup);
            app.FrontrearRatioTab.Title = 'Front/rear Ratio';
            app.AverageratioLabel = uilabel(app.FrontrearRatioTab);
            app.AverageratioLabel.Text = 'Average ratio :';
            app.meanratio = uilabel(app.FrontrearRatioTab);
            app.UIAxes2 = uiaxes(app.FrontrearRatioTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Roll_Gradient(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end