classdef Track_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Trackplot          matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        RightPanel         matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        DataDropDownLabel  matlab.ui.control.Label
        DataDropDown       matlab.ui.control.DropDown
        Label              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp; % Datavisu
        data_struct;
    end
    
    methods (Access = private)
        
         function [] = track_plot(app, X, Y, T)
            % X et Y sont des coordonnées venant de lat_longi2X_Y
            % speed est en km/h
            Labelt = app.DataDropDown.Value;
            cla(app.UIAxes,'reset') % reset le graph
            z = zeros(size(X'));
            col = T';  % This is the color, vary with x in this case.
            surface(app.UIAxes, [X';X'],[Y';Y'],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            c = colorbar(app.UIAxes);
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            c.Label.String = Labelt;
            app.Label.Text = Labelt;
            axis(app.UIAxes,[min(X)-10,max(X)+10,min(Y)-10,max(Y)+10]);
            set(app.UIAxes,'Visible','off')
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            app.CallingApp = Mainapp;
            app.data_struct = app.CallingApp.data_struct;
            datalist=sort({app.data_struct.Name});
            app.DataDropDown.Items = datalist;
        
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            indexdata = get_data_index(app.data_struct, value);
            data = app.data_struct(indexdata).Data;
            
            Xindex = get_data_index(app.data_struct, 'X');
            X = get_data(app.data_struct,'X');
            Y = get_data(app.data_struct,'Y');
            if app.data_struct(Xindex).Frequency == app.data_struct(indexdata).Frequency
                track_plot(app,X, Y, data);
            else
                P1 = strcat('F',num2str(app.data_struct(Xindex).Frequency)); %le nom des temps pour les bonnes fréquences 
                P2 = strcat('F',num2str(app.data_struct(indexdata).Frequency));
                idP1 = strcmp({app.data_struct.Name}, P1)==1; %les listes des temps pour les bonnes fréquences 
                idP2 = strcmp({app.data_struct.Name}, P2)==1;
                
                data = interp1(app.data_struct(idP2).Data,data,app.data_struct(idP1).Data); %Interpolation
                track_plot(app,X, Y, data);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Trackplot.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Trackplot = uifigure('Visible', 'off');
            app.Trackplot.Name = 'UI Figure';
            app.Trackplot.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Trackplot);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DataDropDownLabel = uilabel(app.RightPanel);
            app.DataDropDownLabel.Text = 'Data :';
            app.DataDropDown = uidropdown(app.RightPanel);
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.Label = uilabel(app.RightPanel);
            % Show the figure after all components are created
            app.Trackplot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Track_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Trackplot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Trackplot)
        end
    end
end